classdef poliScopo_v4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        firstGrid                      matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout4                    matlab.ui.container.GridLayout
        PlotOptionsPanel               matlab.ui.container.Panel
        CircuitDropDown                matlab.ui.control.DropDown
        CircuitDropDownLabel           matlab.ui.control.Label
        FreqDropDown                   matlab.ui.control.DropDown
        FreqDropDownLabel              matlab.ui.control.Label
        TimeSlider                     matlab.ui.control.Slider
        TimeSliderLabel                matlab.ui.control.Label
        ConnectionPanel                matlab.ui.container.Panel
        ConnectButton_2                matlab.ui.control.StateButton
        COMPortDropDown                matlab.ui.control.DropDown
        COMPortDropDownLabel           matlab.ui.control.Label
        RecordingPanel                 matlab.ui.container.Panel
        RecordButton                   matlab.ui.control.StateButton
        DescriptionTextArea            matlab.ui.control.TextArea
        DescriptionTextAreaLabel       matlab.ui.control.Label
        UserefchangeastriggerCheckBox  matlab.ui.control.CheckBox
        SaveButton                     matlab.ui.control.Button
        SelectfileButton               matlab.ui.control.Button
        FilenameEditField              matlab.ui.control.EditField
        FilenameEditFieldLabel         matlab.ui.control.Label
        GridLayout2                    matlab.ui.container.GridLayout
        GridLayout3                    matlab.ui.container.GridLayout
        thFig                          matlab.ui.control.UIAxes
        posFig                         matlab.ui.control.UIAxes
        ballFig                        matlab.ui.control.UIAxes
        backFig                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        arduinoCOM  % Description
        field
        isconnected
        isrecording
        out
        sample_rate
        buffer
        plot_freq
        convX  
        convY  
        convThX
        convThY
        serial_send
    end
    
    methods (Access = private)
        
        function buffer_update(app)
            app.plot_freq = str2num(app.FreqDropDown.Value);
            app.buffer.sample = round(app.plot_freq*app.TimeSlider.Value);
            app.TimeSlider.Value = app.buffer.sample/app.plot_freq;
            app.buffer.t = [1:app.buffer.sample]/app.plot_freq;
            app.buffer.tx = NaN*zeros(1, app.buffer.sample);
            app.buffer.ty = NaN*zeros(1, app.buffer.sample);
            app.buffer.x = NaN*zeros(1, app.buffer.sample);
            app.buffer.y = NaN*zeros(1, app.buffer.sample);
            app.buffer.p = NaN*zeros(1, app.buffer.sample);
            app.posFig.XLim = [0 app.TimeSlider.Value];
            app.thFig.XLim = [0 app.TimeSlider.Value];
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%            
            cd('C:\Users\ricci\Desktop\Progetto Meccatronica\2D-Ball-Balancing')
            load('ScopeData.mat')
            app.convX   = conv_scope_x; 
            app.convY   = conv_scope_y; 
            app.convThX = conv_scope_thX; 
            app.convThY = conv_scope_thY; 

            app.serial_send = '@11';
            % Buffer Initialization
            app.sample_rate = 50;
            different_freq = flip(divisors(app.sample_rate));
            app.FreqDropDown.Items = cellstr(string(different_freq));
            app.FreqDropDown.Value = string(different_freq(1));
            app.buffer_update

            directories = dir('Optimal Control\OptimalResults\*.mat');
            app.CircuitDropDown.Items = {'none', directories.name};
            app.CircuitDropDown.Value = 'none';
            
            % Position plot Data
            app.posFig.YLim = [x_min+border x_max-border];
            title(app.posFig, 'Position')
            xlabel(app.posFig, 't [s]')
            ylabel(app.posFig, 'pos [m]')

            % Theta plot Data
            app.thFig.YLim = rad2deg([th_min th_max]);
            title(app.thFig, 'Motor Angle')
            xlabel(app.thFig, 't [s]')
            ylabel(app.thFig, 'angle [deg]')
        
            % Ball plot Data
            app.backFig.XLim = [x_min+border x_max-border];
            app.backFig.YLim = [y_min+border y_max-border];  

            app.ballFig.XLim = [x_min+border x_max-border];
            app.ballFig.YLim = [y_min+border y_max-border]; 
            set(app.ballFig,'DataAspectRatio',[1 1 1])
            set(app.backFig,'DataAspectRatio',[1 1 1])
%             hold(app.backFig, 'on')
%             plot(app.backFig, 1024/2 + 200*sin(linspace(0,2*pi,100)), 1024/2 + 200*cos(linspace(0,2*pi,100)), '--k', 'LineWidth', 1.2)
%             rectangle(app.backFig,'Position',[x_min         y_min          x_max           y_min+border], 'FaceColor', 'w', 'EdgeColor', 'w')
%             rectangle(app.backFig,'Position',[x_min         y_min          x_min+border    y_max       ], 'FaceColor', 'w', 'EdgeColor', 'w')
%             rectangle(app.backFig,'Position',[x_min         y_max-border   x_max           y_max       ], 'FaceColor', 'w', 'EdgeColor', 'w')
%             rectangle(app.backFig,'Position',[x_max-border  y_min          x_max           y_max       ], 'FaceColor', 'w', 'EdgeColor', 'w')
%             rectangle(app.backFig,'Position',[x_min+border  y_min+border   x_max-2*border, y_max-2*border])

            % Connection Data
            app.isconnected = 0;
            app.isrecording = 2; 
            app.out.x = []; 
            app.out.y = []; 

%             availableports = serialportlist('available')
            availableports = {'COM13'};
            app.COMPortDropDown.Items = availableports;
            app.COMPortDropDown.Value = availableports{1};


        end

        % Callback function
        function COMPortDropDownValueChanged(app, event)
            value = app.COMPortDropDown.Value;
            
        end

        % Value changed function: ConnectButton_2
        function ConnectButtonValueChanged(app, event)


%             value = app.ConnectButton_2.Value;
            app.isconnected = abs(app.isconnected-1);
           
            
            if app.isconnected == 1
            app.arduinoCOM = serialport('COM5', 19200);
                          
            configureTerminator(app.arduinoCOM, 'CR/LF');    % set terminator property
            flush(app.arduinoCOM)   % flush data already present
            else
                app.arduinoCOM = [];
            end


            ii = 1; 
%           writeline(app.arduinoCOM, app.serial_send)       
            
            while(app.isconnected)
            raw = convertStringsToChars(erase(readline(app.arduinoCOM), '@'));
            
            app.field.tx = app.convThX(str2double(raw(1 : 4)));
            app.field.ty = app.convThY(str2double(raw(5 : 8)));
            app.field.x  = app.convX(str2double(raw(9 : 12)));
            app.field.y  = app.convY(str2double(raw(13 : 16)));
            app.field.p  = str2double(raw(17 : 20));


                if app.field.p <10|isnan(app.field.p)
                    app.field.x = NaN; 
                    app.field.y = NaN;
                end
            app.out.x = [app.out.x, app.field.x];
            app.out.y = [app.out.y, app.field.y];
%             if mod(ii, app.buffer.sample)==0
            app.buffer.tx = [app.buffer.tx(2:end), app.field.tx];
            app.buffer.ty = [app.buffer.ty(2:end), app.field.ty];
            app.buffer.x  = [app.buffer.x(2:end), app.field.x];
            app.buffer.y  = [app.buffer.y(2:end), app.field.y];
            app.buffer.p  = [app.buffer.p(2:end), app.field.p];

            velx = diff(app.buffer.x)./diff(app.buffer.t); 
            vely = diff(app.buffer.y)./diff(app.buffer.t); 

%             accx = diff(velx)./diff(app.buffer.t); 
%             accy = diff(vely)./diff(app.buffer.t); 

              plot(app.ballFig, app.buffer.x, app.buffer.y, 'LineWidth', 1.3)
%              quiver(app.ballFig, velx([end-1 end]), vely([end-1 end]))

             hold(app.ballFig, 'on')

            plot(app.ballFig, app.field.x, app.field.y, 'ko', 'MarkerSize',30, 'MarkerFaceColor',[0.65,0.65,0.65])
             hold(app.ballFig, 'off')

            plot(app.posFig, app.buffer.t, app.buffer.x, app.buffer.t, app.buffer.y, 'LineWidth', 1.3)
%             plot(app.posFig, app.buffer.t(1:end-1), velx, app.buffer.t(1:end-1), vely, 'LineWidth', 1.3)
            
legend(app.posFig,{'X','Y'})    

           plot(app.thFig, app.buffer.t, rad2deg(app.buffer.tx), app.buffer.t, rad2deg(app.buffer.ty), 'LineWidth', 1.3)
            legend(app.thFig,{'X','Y'})    
%             end
            ii = ii+1; 
            end
        end

        % Value changed function: RecordButton
        function RecordButtonValueChanged(app, event)
% %             value = app.RecordButton.Value;
            app.isrecording = abs(app.isrecording-1);
%     
            if app.isrecording == 0
                app.out.t = [1:length(app.out.x)]*app.sample_rate; 
                out = app.out; 
                save('prova', 'out')
            elseif app.isrecording == 1
                app.out.x = []; 
                app.out.y = []; 
            end

        end

        % Value changed function: TimeSlider
        function TimeSliderValueChanged(app, event)

                app.buffer_update
        end

        % Callback function
        function UpdateButtonPushed(app, event)
%                         while(app.isconnected)
% 
%               writeline(app.arduinoCOM, app.serial_send)       
%                         end
        end

        % Value changed function: CircuitDropDown
        function CircuitDropDownValueChanged(app, event)
            value = app.CircuitDropDown.Value;
            if strcmp(value, 'none')
                cla(app.backFig);
            else
                load(strcat('Optimal Control\OptimalResults\', value))
                plot(app.backFig, x(2, :), x(4, :), '--k')
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.firstGrid = uifigure('Visible', 'off');
            app.firstGrid.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.firstGrid);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.backFig = uiaxes(app.GridLayout2);
            title(app.backFig, 'Position Monitor')
            app.backFig.XTick = [];
            app.backFig.YTick = [];
            app.ballFig = uiaxes(app.GridLayout2);
            title(app.ballFig, '  ')
            app.ballFig.XTick = [];
            app.ballFig.YTick = [];
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.posFig = uiaxes(app.GridLayout3);
            title(app.posFig, 'Title')
            xlabel(app.posFig, 'X')
            ylabel(app.posFig, 'Y')
            zlabel(app.posFig, 'Z')
            app.thFig = uiaxes(app.GridLayout3);
            title(app.thFig, 'Title')
            xlabel(app.thFig, 'X')
            ylabel(app.thFig, 'Y')
            zlabel(app.thFig, 'Z')
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.RecordingPanel = uipanel(app.GridLayout4);
            app.RecordingPanel.Title = 'Recording';
            app.FilenameEditFieldLabel = uilabel(app.RecordingPanel);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.RecordingPanel, 'text');
            app.SelectfileButton = uibutton(app.RecordingPanel, 'push');
            app.SelectfileButton.Text = 'Select file';
            app.SaveButton = uibutton(app.RecordingPanel, 'push');
            app.SaveButton.Text = 'Save';
            app.UserefchangeastriggerCheckBox = uicheckbox(app.RecordingPanel);
            app.UserefchangeastriggerCheckBox.Text = 'Use ref. change as trigger';
            app.DescriptionTextAreaLabel = uilabel(app.RecordingPanel);
            app.DescriptionTextAreaLabel.Text = 'Description';
            app.DescriptionTextArea = uitextarea(app.RecordingPanel);
            app.RecordButton = uibutton(app.RecordingPanel, 'state');
            app.RecordButton.ValueChangedFcn = createCallbackFcn(app, @RecordButtonValueChanged, true);
            app.RecordButton.Text = 'Record';
            app.ConnectionPanel = uipanel(app.GridLayout4);
            app.ConnectionPanel.Title = 'Connection';
            app.COMPortDropDownLabel = uilabel(app.ConnectionPanel);
            app.COMPortDropDownLabel.Text = 'COM Port';
            app.COMPortDropDown = uidropdown(app.ConnectionPanel);
            app.COMPortDropDown.Items = {};
            app.COMPortDropDown.Value = {};
            app.ConnectButton_2 = uibutton(app.ConnectionPanel, 'state');
            app.ConnectButton_2.ValueChangedFcn = createCallbackFcn(app, @ConnectButtonValueChanged, true);
            app.ConnectButton_2.Text = 'Connect';
            app.PlotOptionsPanel = uipanel(app.GridLayout4);
            app.PlotOptionsPanel.Title = 'Plot Options';
            app.TimeSliderLabel = uilabel(app.PlotOptionsPanel);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.PlotOptionsPanel);
            app.TimeSlider.Limits = [1 11];
            app.TimeSlider.ValueChangedFcn = createCallbackFcn(app, @TimeSliderValueChanged, true);
            app.TimeSlider.Value = 3;
            app.FreqDropDownLabel = uilabel(app.PlotOptionsPanel);
            app.FreqDropDownLabel.Text = 'Freq';
            app.FreqDropDown = uidropdown(app.PlotOptionsPanel);
            app.CircuitDropDownLabel = uilabel(app.PlotOptionsPanel);
            app.CircuitDropDownLabel.Text = 'Circuit';
            app.CircuitDropDown = uidropdown(app.PlotOptionsPanel);
            app.CircuitDropDown.ValueChangedFcn = createCallbackFcn(app, @CircuitDropDownValueChanged, true);
            % Show the figure after all components are created
            app.firstGrid.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = poliScopo_v4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.firstGrid)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.firstGrid)
        end
    end
end